classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        MtodoMatemticoLabel_2         matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        CalcularButton                matlab.ui.control.StateButton
        HsLabel                       matlab.ui.control.Label
        bkLabel                       matlab.ui.control.Label
        ErrorLabel                    matlab.ui.control.Label
        ErrorLabel_2                  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        akLabel                       matlab.ui.control.Label
        UIAxes2_3                     matlab.ui.control.UIAxes
        UIAxes2_4                     matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        UIAxes1                       matlab.ui.control.UIAxes
        LoadDataButton                matlab.ui.control.Button
        FilterButton                  matlab.ui.control.StateButton
        FilterOrderSpinnerLabel       matlab.ui.control.Label
        FilterOrderSpinner            matlab.ui.control.Spinner
        IdentificacindeSistemasLabel  matlab.ui.control.Label
        DatosdelSistemaLabel          matlab.ui.control.Label
        UIAxes2_5                     matlab.ui.control.UIAxes
        UIAxes2_6                     matlab.ui.control.UIAxes
        MatlabEstimateLabel           matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        MtodoIterativoLabel           matlab.ui.control.Label
        UIAxes2_2                     matlab.ui.control.UIAxes
        CalcularButton_2              matlab.ui.control.StateButton
        HsLabel_2                     matlab.ui.control.Label
        bkLabel_2                     matlab.ui.control.Label
        ErrorLabel_3                  matlab.ui.control.Label
        ErrorLabel_4                  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        akLabel_2                     matlab.ui.control.Label
        ProcessTimeLabel              matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        ErrorEditFieldLabel           matlab.ui.control.Label
        ErrorEditField                matlab.ui.control.NumericEditField
        AlphaEditFieldLabel           matlab.ui.control.Label
        AlphaEditField                matlab.ui.control.NumericEditField
        ItersEditFieldLabel           matlab.ui.control.Label
        ItersEditField                matlab.ui.control.NumericEditField
        DeltaEditFieldLabel           matlab.ui.control.Label
        DeltaEditField                matlab.ui.control.NumericEditField
        TypeDropDownLabel             matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        StatusLabel                   matlab.ui.control.Label
        StatusLabel_2                 matlab.ui.control.Label
        ItersLabel                    matlab.ui.control.Label
        ItersLabel_2                  matlab.ui.control.Label
        UIAxes2_7                     matlab.ui.control.UIAxes
        UIAxes2_8                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [filename pathname] = uigetfile({'*.csv'}, 'File Selector');
            global y  y_filter t Ts;
            data = csvread(strcat(pathname, filename), 1, 0);
            %axes(app.UIAxes1)
            t=data(:,1);
            y=data(:,3);
            Ts = t(2)-t(1);
            [y, mu, sigma] = featureNormalize(y);
            plot(app.UIAxes1,t, y)
            y_filter = y;
        end

        % Value changed function: FilterButton
        function FilterButtonValueChanged(app, event)
            order = app.FilterOrderSpinner.Value;
            global y_filter t y Ts
            
            Fs = 1/Ts;
            if order>0
                framelen = 21;
                Wc = 2*pi*0.1;
                [bk, ak] = butter(order, 1, 's');
                [bk, ak] = lp2hp(bk, ak, Wc);
                [bk, ak] = bilinear(bk, ak, Fs);
                y_filter = filter(bk, ak, y); 
                y_filter = sgolayfilt(y_filter,order*2,framelen);
                y_filter = filter(ones(1, order)/order, 1, y_filter);
            end
            plot(app.UIAxes1, t, y, t, y_filter)
            legend(app.UIAxes1,'Data','Data filtered')
            
            txy = tfestimate(t,y_filter);
            plot(app.UIAxes2_5, abs(txy))
            plot(app.UIAxes2_6, angle(txy))
        end

        % Value changed function: CalcularButton
        function CalcularButtonValueChanged(app, event)
            global y_filter t Gs_Mat
            syms s
            [bk, ak, type] = Resp2tf(y_filter,t);
            Gs_Mat=tf(bk,ak);
            app.bkLabel.Text = string(bk);
            app.akLabel.Text = string(ak(1))+'s^2 +'+string(ak(2))+'s +'+string(ak(3));
            y_math = impulse(Gs_Mat, t);
            [y_math, mu, sigma] = featureNormalize(y_math);
            plot(app.UIAxes2, t, y_filter, t, y_math)
            legend(app.UIAxes2,'Data filtered','Data estimated')
            error = computeCost(y_math, y_filter)*100;
            app.ErrorLabel_2.Text = string(error);
            options = bodeoptions;
            options.FreqUnits = 'Hz'; % or 'rad/second', 'rpm', etc.
            [mag,phase,wout] = bode(Gs_Mat, options);
            mag = reshape(mag,1,[]);
            phase = reshape(phase,1,[]);
            wout = reshape(wout,1,[]);
            plot(app.UIAxes2_3,wout, mag)
            plot(app.UIAxes2_4, wout, phase)
            figure(1)
            pzmap(Gs_Mat)
        end

        % Value changed function: CalcularButton_2
        function CalcularButton_2ValueChanged(app, event)
            global y_filter t
            theta_min = [0    0    0.01    0   -1];
            theta_max = [1    1    10*pi    1   1];
            theta = [0.5+rand() rand() pi/2+2*pi*rand() 0.5+rand() rand()];
            app.StatusLabel_2.Text = 'Processing';
            tic
            alpha = app.AlphaEditField.Value;
            delta = app.DeltaEditField.Value;
            num_iters = app.ItersEditField.Value;
            error = app.ErrorEditField.Value;
            if alpha <=0
                alpha = 0.99;
            end
            if delta <=0
                delta = 0.01;
            end
            if num_iters<=0
                num_iters = 1e4;
            end
            if error <=0
                error = 0.001;
            end
            type = string(app.TypeDropDown.Value);
            app.ErrorLabel_4.Text = type;
            [theta, J_history, iters] =  gradientDescent(t, y_filter, theta, alpha, delta, num_iters, type, error, theta_min, theta_max);
            y_estimate = computefunction(t, theta, type);
            app.ErrorLabel_4.Text = string(J_history(iters)*100);
            app.ItersLabel_2.Text = string(iters);
            plot(app.UIAxes2_2, t, y_estimate, t, y_filter)
            txy = tfestimate(t,y_estimate);
            plot(app.UIAxes2_7, abs(txy))
            plot(app.UIAxes2_8, angle(txy))
            time = toc;
            app.TimeLabel.Text = string(time);
            app.StatusLabel_2.Text = 'Finish';


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {769, 769, 769};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {769, 769};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {352, '1x', 417};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MtodoMatemticoLabel_2 = uilabel(app.LeftPanel);
            app.MtodoMatemticoLabel_2.Text = 'Método Matemático';
            app.UIAxes2 = uiaxes(app.LeftPanel);
            title(app.UIAxes2, 'Estimate Impulse Response')
            xlabel(app.UIAxes2, 't [s]')
            ylabel(app.UIAxes2, ' Y [cm/K]')
            app.CalcularButton = uibutton(app.LeftPanel, 'state');
            app.CalcularButton.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonValueChanged, true);
            app.CalcularButton.Text = 'Calcular';
            app.HsLabel = uilabel(app.LeftPanel);
            app.HsLabel.Text = 'H(s)';
            app.bkLabel = uilabel(app.LeftPanel);
            app.bkLabel.Text = 'bk';
            app.ErrorLabel = uilabel(app.LeftPanel);
            app.ErrorLabel.Text = 'Error';
            app.ErrorLabel_2 = uilabel(app.LeftPanel);
            app.ErrorLabel_2.Text = 'Error';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '-----------------------------------------------';
            app.akLabel = uilabel(app.LeftPanel);
            app.akLabel.Text = 'ak';
            app.UIAxes2_3 = uiaxes(app.LeftPanel);
            title(app.UIAxes2_3, 'Mag')
            xlabel(app.UIAxes2_3, 'F [Hz]')
            ylabel(app.UIAxes2_3, 'Abs')
            app.UIAxes2_4 = uiaxes(app.LeftPanel);
            title(app.UIAxes2_4, 'Phase')
            xlabel(app.UIAxes2_4, 'F [Hz]')
            ylabel(app.UIAxes2_4, 'angle [deg]')
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes1 = uiaxes(app.CenterPanel);
            title(app.UIAxes1, 'Response')
            xlabel(app.UIAxes1, 't [s]')
            ylabel(app.UIAxes1, 'Y [cm/K]')
            app.LoadDataButton = uibutton(app.CenterPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.FilterButton = uibutton(app.CenterPanel, 'state');
            app.FilterButton.ValueChangedFcn = createCallbackFcn(app, @FilterButtonValueChanged, true);
            app.FilterButton.Text = 'Filter';
            app.FilterOrderSpinnerLabel = uilabel(app.CenterPanel);
            app.FilterOrderSpinnerLabel.Text = 'Filter Order';
            app.FilterOrderSpinner = uispinner(app.CenterPanel);
            app.IdentificacindeSistemasLabel = uilabel(app.CenterPanel);
            app.IdentificacindeSistemasLabel.Text = 'Identificación de Sistemas';
            app.DatosdelSistemaLabel = uilabel(app.CenterPanel);
            app.DatosdelSistemaLabel.Text = 'Datos del Sistema';
            app.UIAxes2_5 = uiaxes(app.CenterPanel);
            title(app.UIAxes2_5, 'Mag')
            xlabel(app.UIAxes2_5, 'F [Hz]')
            ylabel(app.UIAxes2_5, 'Abs')
            app.UIAxes2_6 = uiaxes(app.CenterPanel);
            title(app.UIAxes2_6, 'Phase')
            xlabel(app.UIAxes2_6, 'F [Hz]')
            ylabel(app.UIAxes2_6, 'angle [deg]')
            app.MatlabEstimateLabel = uilabel(app.CenterPanel);
            app.MatlabEstimateLabel.Text = 'Matlab Estimate';
            app.RightPanel = uipanel(app.GridLayout);
            app.MtodoIterativoLabel = uilabel(app.RightPanel);
            app.MtodoIterativoLabel.Text = 'Método Iterativo';
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'Estimate Impulse Resoonse')
            xlabel(app.UIAxes2_2, 't [s]')
            ylabel(app.UIAxes2_2, 'Y [cm/K]')
            app.CalcularButton_2 = uibutton(app.RightPanel, 'state');
            app.CalcularButton_2.ValueChangedFcn = createCallbackFcn(app, @CalcularButton_2ValueChanged, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.HsLabel_2 = uilabel(app.RightPanel);
            app.HsLabel_2.Text = 'H(s)';
            app.bkLabel_2 = uilabel(app.RightPanel);
            app.bkLabel_2.Text = 'bk';
            app.ErrorLabel_3 = uilabel(app.RightPanel);
            app.ErrorLabel_3.Text = 'Error';
            app.ErrorLabel_4 = uilabel(app.RightPanel);
            app.ErrorLabel_4.Text = 'Error';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '-----------------------------------------------';
            app.akLabel_2 = uilabel(app.RightPanel);
            app.akLabel_2.Text = 'ak';
            app.ProcessTimeLabel = uilabel(app.RightPanel);
            app.ProcessTimeLabel.Text = 'Process Time: ';
            app.TimeLabel = uilabel(app.RightPanel);
            app.TimeLabel.Text = 'Time';
            app.ErrorEditFieldLabel = uilabel(app.RightPanel);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.AlphaEditFieldLabel = uilabel(app.RightPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.RightPanel, 'numeric');
            app.ItersEditFieldLabel = uilabel(app.RightPanel);
            app.ItersEditFieldLabel.Text = '# Iters';
            app.ItersEditField = uieditfield(app.RightPanel, 'numeric');
            app.DeltaEditFieldLabel = uilabel(app.RightPanel);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.RightPanel, 'numeric');
            app.TypeDropDownLabel = uilabel(app.RightPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.RightPanel);
            app.TypeDropDown.Items = {'Subamortiguado', 'Sobreamortiguado', 'Critico', 'Oscilador'};
            app.TypeDropDown.ItemsData = {'1', '2', '3', '4', ''};
            app.TypeDropDown.Value = '1';
            app.StatusLabel = uilabel(app.RightPanel);
            app.StatusLabel.Text = 'Status:';
            app.StatusLabel_2 = uilabel(app.RightPanel);
            app.StatusLabel_2.Text = 'Status';
            app.ItersLabel = uilabel(app.RightPanel);
            app.ItersLabel.Text = '# Iters';
            app.ItersLabel_2 = uilabel(app.RightPanel);
            app.ItersLabel_2.Text = '# Iters';
            app.UIAxes2_7 = uiaxes(app.RightPanel);
            title(app.UIAxes2_7, 'Mag')
            xlabel(app.UIAxes2_7, 'F [Hz]')
            ylabel(app.UIAxes2_7, 'Abs')
            app.UIAxes2_8 = uiaxes(app.RightPanel);
            title(app.UIAxes2_8, 'Phase')
            xlabel(app.UIAxes2_8, 'F [Hz]')
            ylabel(app.UIAxes2_8, 'angle [deg]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end